classdef ksim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImportMenu                     matlab.ui.container.Menu
        MapsMenu                       matlab.ui.container.Menu
        M0FileMenu                     matlab.ui.container.Menu
        T1FileMenu                     matlab.ui.container.Menu
        T2FileMenu                     matlab.ui.container.Menu
        deltaBFileMenu                 matlab.ui.container.Menu
        ActivationFileMenu             matlab.ui.container.Menu
        ImportMRIStruct                matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        singlekspace                   matlab.ui.container.Menu
        TimeSerieskSpaceMenu           matlab.ui.container.Menu
        ExportMRIStruct                matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        DataViewerPanel                matlab.ui.container.Panel
        PhantomSizeButtonGroup         matlab.ui.container.ButtonGroup
        phantomSize128                 matlab.ui.control.RadioButton
        phantomSize96                  matlab.ui.control.RadioButton
        phantomSize64                  matlab.ui.control.RadioButton
        DViewButton                    matlab.ui.control.Button
        MapDropDown                    matlab.ui.control.DropDown
        MapDropDownLabel               matlab.ui.control.Label
        ViewLabel                      matlab.ui.control.Label
        SliceViewDropDown              matlab.ui.control.DropDown
        SliceLabel                     matlab.ui.control.Label
        SliceSpinner                   matlab.ui.control.Spinner
        PlotActivationCheck            matlab.ui.control.CheckBox
        MapAxes                        matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        MRIParametersTab               matlab.ui.container.Tab
        EESPmsEditFieldLabel           matlab.ui.control.Label
        EESPmsEditField                matlab.ui.control.NumericEditField
        ReconstructionDropDownLabel    matlab.ui.control.Label
        ReconstructionDropDown         matlab.ui.control.DropDown
        deltaB0Check                   matlab.ui.control.CheckBox
        RelaxivityTable                matlab.ui.control.Table
        WMGMCSFLabel                   matlab.ui.control.Label
        ExportSingleKSpaceButton       matlab.ui.control.Button
        DomainDropDown_2Label          matlab.ui.control.Label
        VoxelDataDropDown_2Label       matlab.ui.control.Label
        NumberofCoilsSpinner           matlab.ui.control.Spinner
        NumberofCoilsSpinnerLabel      matlab.ui.control.Label
        FlipAngledegEditFieldLabel     matlab.ui.control.Label
        FlipAngledegEditField          matlab.ui.control.NumericEditField
        TRmsEditField                  matlab.ui.control.NumericEditField
        TRmsEditFieldLabel             matlab.ui.control.Label
        TEmsEditFieldLabel             matlab.ui.control.Label
        TEmsEditField                  matlab.ui.control.NumericEditField
        FieldStrengthTEditFieldLabel   matlab.ui.control.Label
        FieldStrengthTEditField        matlab.ui.control.NumericEditField
        AccelerationFactorLabel        matlab.ui.control.Label
        AccelerationFactorSpinner      matlab.ui.control.Spinner
        TrajectoryDropDownLabel        matlab.ui.control.Label
        TrajectoryDropDown             matlab.ui.control.DropDown
        ViewLabel_2                    matlab.ui.control.Label
        SignalEquationDropdown         matlab.ui.control.DropDown
        DomainDropDown_sample          matlab.ui.control.DropDown
        VoxelDataDropDown_sample       matlab.ui.control.DropDown
        CoilNumberSpinner_sample       matlab.ui.control.Spinner
        CoilNumberSpinner_2Label       matlab.ui.control.Label
        ActivationCheckSingleKspace    matlab.ui.control.CheckBox
        singlekSpaceButton             matlab.ui.control.Button
        singlekSpaceAxes               matlab.ui.control.UIAxes
        TaskDesignTab                  matlab.ui.container.Tab
        SNREditField                   matlab.ui.control.NumericEditField
        SNREditFieldLabel              matlab.ui.control.Label
        InitialRestImagesEditField     matlab.ui.control.NumericEditField
        InitialRestImagesEditFieldLabel  matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        Task1Tab                       matlab.ui.container.Tab
        CNREditField                   matlab.ui.control.NumericEditField
        CNREditFieldLabel              matlab.ui.control.Label
        ActivationPhase                matlab.ui.control.NumericEditField
        PhaseIncreaseLabel             matlab.ui.control.Label
        ActivationMag                  matlab.ui.control.NumericEditField
        ActivationLabel                matlab.ui.control.Label
        Task2Tab                       matlab.ui.container.Tab
        SimulatefMRIExperimentButton   matlab.ui.control.Button
        PlotDesignButton               matlab.ui.control.Button
        TaskImagesperEpochEditField    matlab.ui.control.NumericEditField
        TaskImagesperEpochEditFieldLabel  matlab.ui.control.Label
        RestImagesperEpochEditField    matlab.ui.control.NumericEditField
        RestImagesperEpochEditFieldLabel  matlab.ui.control.Label
        EpochsEditField                matlab.ui.control.NumericEditField
        EpochsEditFieldLabel           matlab.ui.control.Label
        HRFDropDown                    matlab.ui.control.DropDown
        HRFDropDownLabel               matlab.ui.control.Label
        designAxes                     matlab.ui.control.UIAxes
        CenterPanel                    matlab.ui.container.Panel
        StatisticalAnalysisPanel       matlab.ui.container.Panel
        VoxelHistogramButton           matlab.ui.control.Button
        yEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        xEditField_2Label              matlab.ui.control.Label
        Hyperlink_2                    matlab.ui.control.Hyperlink
        SHAKERMarquetteUniversityBodenschatzRowe2025Label  matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        VoxelDataDropDown_2Label_2     matlab.ui.control.Label
        StatsVoxelDataDropDown         matlab.ui.control.DropDown
        DomainDropDown_2Label_2        matlab.ui.control.Label
        StatsDomainDropDown            matlab.ui.control.DropDown
        StatisticDropDown              matlab.ui.control.DropDown
        StatisticDropDownLabel         matlab.ui.control.Label
        MaskEmptySpaceCheckBox         matlab.ui.control.CheckBox
        SuperimposeAnatomicalCheckBox  matlab.ui.control.CheckBox
        statsButton                    matlab.ui.control.Button
        statisticsAxes                 matlab.ui.control.UIAxes
        TimeSeriesPanel                matlab.ui.container.Panel
        DisplayVoxelCheckBox           matlab.ui.control.CheckBox
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        ActivationLabel_2              matlab.ui.control.Label
        ActivationLamp                 matlab.ui.control.Lamp
        VoxelDataDropDown              matlab.ui.control.DropDown
        VoxelDataDropDownLabel         matlab.ui.control.Label
        DomainDropDown                 matlab.ui.control.DropDown
        DomainDropDownLabel            matlab.ui.control.Label
        CoilNumberSpinner              matlab.ui.control.Spinner
        CoilNumberSpinnerLabel         matlab.ui.control.Label
        TimePointSpinner               matlab.ui.control.Spinner
        TimePointSpinnerLabel          matlab.ui.control.Label
        ExportTimeSeriesButton         matlab.ui.control.Button
        VoxelTimeSeriesButton          matlab.ui.control.Button
        timeSeriesAxes                 matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DiagnosticButton               matlab.ui.control.Button
        RNGseedEditField               matlab.ui.control.NumericEditField
        RNGseedEditFieldLabel          matlab.ui.control.Label
        TextOutput                     matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        mapViewInputData3D; Phantom;
        M0Map; T1Map; T2Map; ActMap; deltaB;
        rec_size; kx; ky;
        kSpaceRest; kSpaceTask;
        singlekSpace; coilSensitivities;
        singleImage;
        TE; TR; MASK; timeMap;
        MRI; kSpace;
        reconstructionMethod;

        %fmri objects
        M0MapTask;
        design; sigma;
        kSpaceTimeSeries;
        imageSpaceTimeSeries;
        sigmaImageSpace;

        %stats objects
        statisticsData; Anatomical;
        statisticsInputData; myHist;

        %other
        phaseColorMap; superimposeColorMap;
    end


    methods (Access = private)
        
        function MRI = updateMRIStruct(app)
            app.MRI.SignalEquation = app.SignalEquationDropdown.Value;
            app.MRI.Trajectory = app.TrajectoryDropDown.Value;
            app.MRI.AccelerationFactor = app.AccelerationFactorSpinner.Value;
            app.MRI.FieldStrength = app.FieldStrengthTEditField.Value;
            app.MRI.EchoTime = app.TEmsEditField.Value / 1000; % convert to seconds for save
            app.MRI.RepititionTime = app.TRmsEditField.Value / 1000; % convert to seconds for save
            app.MRI.FlipAngle = app.FlipAngledegEditField.Value;
            app.MRI.NumberOfCoils = app.NumberofCoilsSpinner.Value;
            app.MRI.CoilSensitivities = app.coilSensitivities;
            app.MRI.gamma = 42.58e06; % 42 MHz/T (H nuclei gyromagnetic ratio)
            app.MRI.RelaxivityTable = app.RelaxivityTable;
            app.MRI.ReconstructionSize = length(app.Phantom.M0);
            app.MRI.IncludeB0Inhomogeneity = app.deltaB0Check.Value;
            app.MRI.eesp = app.EESPmsEditField.Value;
            app.MRI.RNGseed = app.RNGseedEditField.Value;

            app.MRI.SNR = app.SNREditField.Value;
            app.MRI.CNR = app.CNREditField.Value;

            app.MRI.InitialRest = app.InitialRestImagesEditField.Value;
            app.MRI.Epochs = app.EpochsEditField.Value;
            app.MRI.RestImages = app.RestImagesperEpochEditField.Value;
            app.MRI.TaskImages = app.TaskImagesperEpochEditField.Value;

            app.MRI.ReconstructionSize = 96;
            if app.phantomSize64.Value
                app.MRI.ReconstructionSize = 64;
            elseif app.phantomSize128.Value
                app.MRI.ReconstructionSize = 128;
            end
            app.RNGseedEditFieldValueChanged()
        end

        %function kSpace = updatekSpaceStruct(app)
        %    app.kSpace.kx = app.kx
        %end

        
        function displaySummary(app)
            updateMRIStruct(app);
           

            starter = strjoin(['The following fMRI time series data was simulated on ', string(datetime('now','TimeZone','local','Format','d-MMM-y')), ' at ', string(datetime('now','TimeZone','local','Format','HH:mm:ss')),' using SHAKER v1.0.'],'');

            sliceviews=app.SliceViewDropDown.Items;
            viewDescription = strjoin(['The simulated time series is of slice ',string(app.SliceSpinner.Value), ' from a size ', app.PhantomSizeButtonGroup.SelectedObject.Text ,' phantom in the ',string(sliceviews(str2double(app.SliceViewDropDown.Value))), ' plane.'],'');
            
            mriDescription1 = strjoin(['The MRI parameters were set to be the following: Acceleration Factor = ',string(app.MRI.AccelerationFactor),', Field Strength = ',string(app.MRI.FieldStrength),'T, TE = ',string(app.MRI.EchoTime*1000)','ms,' ],'');
            mriDescription2 = strjoin(['TR = ',string(app.MRI.RepititionTime * 1000),'ms, Flip Angle = ',string(app.MRI.FlipAngle),'deg, EESP = ',string(app.MRI.eesp),'ms, and Number of Coils = ',string(app.MRI.NumberOfCoils),'.'],'');
            mriDescription3 = strjoin(['The data was simulated with the ',string(app.MRI.SignalEquation),' signal equation using the ',string(app.MRI.Trajectory),' k-space trajectory.'],'');
            
            fmriDescription1 = strjoin(['The experimental design involved an initial ',string(app.InitialRestImagesEditField.Value),' rest images followed by ',string(app.EpochsEditField.Value),' epochs, each consisting of ', string(app.TaskImagesperEpochEditField.Value),' task images followed by ', string(app.RestImagesperEpochEditField.Value),' rest images for a total of ',string(length(app.design)),' images.'],'');
            fmriDescription2 = strjoin(['The SNR was set to ', string(app.SNREditField.Value), ' and the CNR was set to ',string(app.CNREditField.Value),'. There were ',string(app.ActivationPhase.Value), ' degrees of phase added to the activation.' ],'');
            
            reconDescription = strjoin(['Images were reconstructed using the ',string(app.ReconstructionDropDown.Value),' algorithm.'],'');

            app.TextOutput.Value = strjoin([starter,viewDescription,mriDescription1,mriDescription2,mriDescription3,fmriDescription1,fmriDescription2,reconDescription],' ');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%%%% ADD PATHS %%%%%

            appPath = fileparts(mfilename('fullpath'));
            addpath(appPath,'data\');
            addpath(appPath,'functions\');
            addpath(appPath,'reconstructionMethods\');
            addpath(appPath,'kspaceTrajectories\');
            addpath(appPath,'signalEquations\');
            addpath(appPath,'statisticsFunctions\');
            %disp(appPath)
            
            %%%%% LOAD INITIAL MAPS %%%%%
            %PhantomFileName = fullfile(appPath,'data/Phantom96.mat');
            %tmpPhantom96 = load(PhantomFileName);
            tmpPhantom96 = load('Phantom96.mat');
            app.Phantom = tmpPhantom96.Phantom;
            %ActFileName = fullfile(appPath,'data/ActMap96.mat');
            %app.ActMap = load(ActFileName);
            app.ActMap = load('ActMap96.mat');
            
            app.phaseColorMap = load('phaseMap.txt');
            app.superimposeColorMap = load('supMap.txt');

            %%%%% INITIALIZE VALUES %%%%%
            wm=[0.71,832,54.6];
            gm=[.83,1331,59.7];
            csf=[1,4000,2200];
            M=[wm; gm; csf;];
            app.RelaxivityTable.Data=M;
            
            app.SignalEquationDropdownDropDownOpening()
            app.TrajectoryDropDownOpening()
            app.ReconstructionDropDownOpening()


            %%%%% INITIALIZE MAP VIEWER %%%%%
            app.UpdateMapsPlot()
            %app.singlekSpaceButtonPushed() %slows startup too much, not necessary


        end

        % Button pushed function: DiagnosticButton
        function DiagnosticButtonPushed(app, event)
            disp(app.RelaxivityTable.Data(1,1))
            disp(app.RelaxivityTable.Data(2,1))
            disp(app.RelaxivityTable.Data(3,1))
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {918, 918, 918};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {918, 918};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {564, '1x', 347};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Menu selected function: M0FileMenu
        function M0FileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.M0FileNameEditField.Value=filename;
                app.M0Map = load(fullfile(path,filename));            
                app.MapViewSelectSelectionChanged(app);
                app.rec_size = length(app.M0Map.M0Map);
                app.SliceSpinner.Limits = [1,app.rec_size];
                %app.UpdateMapsPlot(app,event);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: T1FileMenu
        function T1FileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.T1FileNameEditField.Value=filename;
                app.T1Map = load(fullfile(path,filename));         
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event);
                %figure(app.UIFigure);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: T2FileMenu
        function T2FileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.T2FileNameEditField.Value=filename;
                app.T2Map = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event);
                %figure(app.UIFigure);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: deltaBFileMenu
        function deltaBFileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.dBFileNameEditField.Value=filename;
                app.dBMap = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event)
                %figure(app.UIFigure);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: ActivationFileMenu
        function ActivationFileMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                %app.ActFileNameEditField.Value=filename;
                app.ActMap = load(fullfile(path,filename));
                app.MapViewSelectSelectionChanged(app);
                %app.UpdateMapsPlot(app,event)
                %figure(app.UIFigure);
            end
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: ImportMRIStruct
        function ImportMRIStructMenuSelected(app, event)
            [filename, path] = uigetfile();
            if ~isequal(filename,0)
                app.MRI = load(fullfile(path,filename));
                app.MRI = app.MRI.MRI;
            end

            app.SignalEquationDropdown.Value = app.MRI.SignalEquation;
            app.TrajectoryDropDown.Value = app.MRI.Trajectory;
            app.AccelerationFactorSpinner.Value = app.MRI.AccelerationFactor;
            app.FieldStrengthTEditField.Value = app.MRI.FieldStrength;
            app.TEmsEditField.Value = app.MRI.EchoTime * 1000; % converting to ms for shaker
            app.TRmsEditField.Value = app.MRI.RepititionTime * 1000; % converting to ms for shaker
            app.FlipAngledegEditField.Value = app.MRI.FlipAngle;
            app.NumberofCoilsSpinner.Value = app.MRI.NumberOfCoils;
            app.RelaxivityTable = app.MRI.RelaxivityTable;
            app.deltaB0Check.Value = app.MRI.IncludeB0Inhomogeneity;
            app.EESPmsEditField.Value = app.MRI.eesp;
            app.RNGseedEditField.Value = app.MRI.RNGseed;

            app.SNREditField.Value = app.MRI.SNR;
            app.CNREditField.Value = app.MRI.CNR;
            
            app.InitialRestImagesEditField.Value = app.MRI.InitialRest;
            app.EpochsEditField.Value = app.MRI.Epochs;
            app.RestImagesperEpochEditField.Value = app.MRI.RestImages;
            app.TaskImagesperEpochEditField.Value = app.MRI.TaskImages;


            if app.MRI.ReconstructionSize == 64
                app.phantomSize64.Value=1;
            elseif app.MRI.ReconstructionSize == 96
                app.phantomSize96.Value=1;
            elseif app.MRI.ReconstructionSize == 128
                app.phantomSize128.Value=1;
            end


            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Callback function: MapAxes, MapDropDown, PhantomSizeButtonGroup, 
        % ...and 3 other components
        function UpdateMapsPlot(app, event)
            % select the correct map
            mapVal = app.MapDropDown.Value;
            if mapVal == 1
                data = app.Phantom.M0;
            elseif mapVal == 2
                data = app.Phantom.T1;
            elseif mapVal == 3
                data = app.Phantom.T2;
            elseif mapVal == 4
                data = app.Phantom.deltaB;
            end


            app.SliceSpinner.Limits=[1,96];
            tmpAM = app.ActMap.ActMap;
            if app.phantomSize64.Value
                data = imresize3(data,64/96,method='nearest');
                tmpAM = imresize3(app.ActMap.ActMap,64/96,method='nearest');
                app.SliceSpinner.Limits=[1,64];
            % skip if 96, this is the default phantom size
            elseif app.phantomSize128.Value
                data = imresize3(data,128/96,method='nearest');
                tmpAM = imresize3(app.ActMap.ActMap,128/96,method='nearest');
                app.SliceSpinner.Limits=[1,128];
            end

            app.mapViewInputData3D = data;
            

            % get slice number and view angle
            
            sliceN = app.SliceSpinner.Value;
            sliceView = str2double(app.SliceViewDropDown.Value);
            if sliceView == 1 % axial
                data = squeeze(data(:,:,sliceN));
                tmpAM = squeeze(tmpAM(:,:,sliceN));
            elseif sliceView == 2 % sagital
                data = rot90(squeeze(data(:,sliceN,:)));
                tmpAM = rot90(squeeze(tmpAM(:,sliceN,:)));
            elseif sliceView == 3 % coronal
                data = rot90(squeeze(data(sliceN,:,:)));
                tmpAM = rot90(squeeze(tmpAM(sliceN,:,:)));
            end


            % plot data
            if mapVal == 1 % proton density
                imagesc(app.MapAxes,data,[0 1]);
                colormap(app.MapAxes,'gray');
            elseif mapVal == 2 % t 1
                imagesc(app.MapAxes,data,[0 4]);
                colormap(app.MapAxes,'jet')
            elseif mapVal == 3 % t2
                imagesc(app.MapAxes,data,[0 3]);
                colormap(app.MapAxes,'jet')
            elseif mapVal == 4 % delta B
                imagesc(app.MapAxes,data,[min(data,[],'all'),max(data,[],'all')]);
                colormap(app.MapAxes,'jet')
            end
            colorbar(app.MapAxes);
            
            % plot activation
            if app.PlotActivationCheck.Value == 1
                hold(app.MapAxes, 'on')
                imagesc(app.MapAxes,tmpAM,'AlphaData',0.75)
                hold(app.MapAxes, 'off')
            end
        end

        % Button pushed function: DViewButton
        function threeDViewButtonPushed(app, event)
            data = app.mapViewInputData3D;
            
            sliceN = app.SliceSpinner.Value;
            sliceView = str2double(app.SliceViewDropDown.Value);
            if sliceView == 1 % axial
                volshow(data(:,:,1:sliceN),RenderingStyle="CinematicRendering");
            elseif sliceView == 2 % sagital
                volshow(data(:,1:sliceN,:),RenderingStyle="CinematicRendering");
            elseif sliceView == 3 % coronal
                volshow(data(1:sliceN,:,:),RenderingStyle="CinematicRendering");
            end
        end

        % Drop down opening function: SignalEquationDropdown
        function SignalEquationDropdownDropDownOpening(app, event)
            signalEquationFiles = dir(fullfile('signalEquations/', '*.m'));
            app.SignalEquationDropdown.Items =  {signalEquationFiles.name}';
        end

        % Value changed function: SignalEquationDropdown
        function SignalEquationDropdownValueChanged(app, event)
            value = app.SignalEquationDropdown.Value;
            if (strcmp(value,'InversionRecovery_SigEq.m') || strcmp(value,'SpinEcho_SigEq.m'))
                app.FlipAngledegEditField.Value = 90;
                app.FlipAngledegEditField.Editable = 'off';
            else
                app.FlipAngledegEditField.Editable = 'on';
            end
        end

        % Drop down opening function: TrajectoryDropDown
        function TrajectoryDropDownOpening(app, event)
            trajectoryFiles = dir(fullfile('kspaceTrajectories/', '*.m'));
            app.TrajectoryDropDown.Items =  {trajectoryFiles.name}';
        end

        % Drop down opening function: ReconstructionDropDown
        function ReconstructionDropDownOpening(app, event)
            reconstructionFiles = dir(fullfile('reconstructionMethods/', '*.m'));
            app.ReconstructionDropDown.Items =  {reconstructionFiles.name}';
        end

        % Button pushed function: singlekSpaceButton
        function singlekSpaceButtonPushed(app, event)

            updateMRIStruct(app);

            %get maps
            app.M0Map = app.Phantom.M0;
            app.T1Map = app.Phantom.T1;
            app.T2Map = app.Phantom.T2;
            app.deltaB = app.Phantom.deltaB;
            tmpActMap = app.ActMap.ActMap;


            % rescale maps based on relaxivity table
            app.M0Map(app.M0Map<0.1) = 0;
            app.M0Map(app.M0Map==0.71) = app.RelaxivityTable.Data(1,1);% should be in [0,1]
            app.M0Map(app.M0Map==0.83) = app.RelaxivityTable.Data(2,1);
            app.M0Map(app.M0Map==1) = app.RelaxivityTable.Data(3,1);
            
            app.T1Map(app.T1Map<0.10) = 0;
            app.T1Map(app.T1Map==0.832) = app.RelaxivityTable.Data(1,2)/1000;% convert ms to s
            app.T1Map(app.T1Map==1.331) = app.RelaxivityTable.Data(2,2)/1000;
            app.T1Map(app.T1Map==4) = app.RelaxivityTable.Data(3,2)/1000;
            
            app.T2Map(app.T2Map>1000) = 1e07;
            app.T2Map(app.T2Map==0.0546) = app.RelaxivityTable.Data(1,3)/1000;% convert ms to s
            app.T2Map(app.T2Map==0.0597) = app.RelaxivityTable.Data(2,3)/1000;
            app.T2Map(app.T2Map==2.2) = app.RelaxivityTable.Data(3,3)/1000;


            % resize phantom
            app.MRI.ReconstructionSize = 96;
            if app.phantomSize64.Value
                app.MRI.ReconstructionSize = 64;
                app.M0Map = imresize3(app.M0Map,64/96,method='nearest');
                app.T1Map = imresize3(app.T1Map,64/96,method='nearest');
                app.T2Map = imresize3(app.T2Map,64/96,method='nearest');
                app.deltaB = imresize3(app.deltaB,64/96,method='nearest');
                tmpActMap = imresize3(tmpActMap,64/96,method='nearest');
            elseif app.phantomSize128.Value
                app.MRI.ReconstructionSize = 128;
                app.M0Map = imresize3(app.M0Map,128/96,method='nearest');
                app.T1Map = imresize3(app.T1Map,128/96,method='nearest');
                app.T2Map = imresize3(app.T2Map,128/96,method='nearest');
                app.deltaB = imresize3(app.deltaB,128/96,method='nearest');
                tmpActMap = imresize3(tmpActMap,128/96,method='nearest');
            end


            %get input values
            sliceN = app.SliceSpinner.Value;
            sliceView = str2double(app.SliceViewDropDown.Value);

            %select the proper slice and orientation
            if sliceView == 1 % axial
                app.M0Map = squeeze(app.M0Map(:,:,sliceN));
                app.T1Map = squeeze(app.T1Map(:,:,sliceN));
                app.T2Map = squeeze(app.T2Map(:,:,sliceN));
                app.deltaB = squeeze(app.deltaB(:,:,sliceN));
                tmpActMap = squeeze(tmpActMap(:,:,sliceN));
            elseif sliceView == 2 % sagital
                app.M0Map = rot90(squeeze(app.M0Map(:,sliceN,:)));
                app.T1Map = rot90(squeeze(app.T1Map(:,sliceN,:)));
                app.T2Map = rot90(squeeze(app.T2Map(:,sliceN,:)));
                app.deltaB = rot90(squeeze(app.deltaB(:,sliceN,:)));
                tmpActMap = rot90(squeeze(tmpActMap(:,sliceN,:)));
            elseif sliceView == 3 % coronal
                app.M0Map = rot90(squeeze(app.M0Map(sliceN,:,:)));
                app.T1Map = rot90(squeeze(app.T1Map(sliceN,:,:)));
                app.T2Map = rot90(squeeze(app.T2Map(sliceN,:,:)));
                app.deltaB = rot90(squeeze(app.deltaB(sliceN,:,:)));
                tmpActMap = rot90(squeeze(tmpActMap(sliceN,:,:)));
            end
            
            % make mask and anatomical
            app.MASK = (app.M0Map./max(app.M0Map,[],'all')) > (1/10);
            app.Anatomical = app.M0Map;
            %app.Anatomical = M0Map.*(1-exp(-app.MRI.RepititionTime ./T1Map)).*exp(-app.MRI.EchoTime ./ T2Map);
            %app.Anatomical = M0Map.*T1Map;

            % calculate k-space coordinate based on user-defined trajectory function
            trajectory = app.TrajectoryDropDown.Value;
            [app.kSpace.kX, app.kSpace.kY, app.kSpace.timeMap] = eval(strcat(trajectory(1:end-2),'(app.MRI)'));


            % aspect ratio change.. not necessary
            %{
            if strcmp(trajectory,'Cartesian_kspace.m')
                %[app.kSpace.kX, app.kSpace.kY, app.kSpace.timeMap] = Cartesian_kspace(app.MRI);
                app.singlekSpaceAxes.DataAspectRatio = [1 app.AccelerationFactorSpinner.Value 1];
            elseif strcmp(trajectory,'Radial_kspace.m')
                %[app.kSpace.kX, app.kSpace.kY, app.kSpace.timeMap] = Radial_kspace(app.MRI);
                app.singlekSpaceAxes.DataAspectRatio = [app.AccelerationFactorSpinner.Value 1 1];
            end
            %}


            % fmri bet0 and beta1, noise statistics
            nkx = length(app.kSpace.kX);
            nky = length(app.kSpace.kY);
            relaxivityData = app.RelaxivityTable.Data;          
            sigmaImageSpace = 1;
            %beta0 =  M0Map * (app.SNREditField.Value * sigmaImageSpace) / relaxivityData(2,1); % do SNRT based on GM M0 value, as done for phantom from dr rowe. 11/11/2024
            %M0Map = beta0;
            %sigmasqRayleigh = (beta0 / app.SNREditField.Value)^2; %magnitude noise is ricean distributed
            %sigmasqImagReal = sigmasqRayleigh *2 / (4-pi); % get real/imag normal dist. variance from rayleigh
            %app.sigma = sqrt((app.sigma)^2 * (nkx * nky)); % convert to kspace (FT scaling)
            %app.sigma = app.SNREditField.Value;
            %beta1 = app.CNREditField.Value * sigmaImageSpace * relaxivityData(2,1);
            %M0MapTask = (M0Map + tmpActMap*beta1).*exp(sqrt(-1)*tmpActMap*(pi/180*app.ActivationPhase.Value));


            % fmri beta0, beta1, sigma
            %app.sigma = 1./(app.SNREditField.Value+0.000001);
            app.sigma = 20 * relaxivityData(2,1) / (app.SNREditField.Value+0.00001);
            %beta1 = app.CNREditField.Value * relaxivityData(2,1); % scale by 0 G value
            app.M0MapTask = app.M0Map.*(1 + tmpActMap .* app.CNREditField.Value / app.SNREditField.Value).*exp(sqrt(-1)*tmpActMap*(pi/180*app.ActivationPhase.Value));


            % generate coil sensitivities
            if app.MRI.NumberOfCoils > 1
                app.coilSensitivities = generate_coils(app.MRI.ReconstructionSize,app.MRI.ReconstructionSize,app.MRI.NumberOfCoils,0);
            else
                app.coilSensitivities = ones(size(app.M0Map));
            end
            %coilSensitivities=app.coilSensitivities;
            

            updateMRIStruct(app);
            

            % generate kspace based on user defined signal equation
            signalEquation = app.SignalEquationDropdown.Value;
            app.kSpaceRest = eval(strcat(signalEquation(1:end-2),'(app.M0Map,app.T1Map,app.T2Map,app.deltaB,app.kSpace,app.MRI)'));
            app.kSpaceTask = eval(strcat(signalEquation(1:end-2),'(app.M0MapTask,app.T1Map,app.T2Map,app.deltaB,app.kSpace,app.MRI)'));

            updateMRIStruct(app);
            app.updateSingleKspacePlot()


        end

        % Value changed function: ActivationCheckSingleKspace, 
        % ...and 3 other components
        function updateSingleKspacePlot(app, event)
            voxelData = str2double(app.VoxelDataDropDown_sample.Value);
            domain = str2double(app.DomainDropDown_sample.Value);
            coilN = app.CoilNumberSpinner_sample.Value;
    
            noise = app.sigma * (randn(size(app.kSpaceRest)) + randn(size(app.kSpaceRest))*sqrt(-1));
            noise = noise / app.NumberofCoilsSpinner.Value;


            if app.ActivationCheckSingleKspace.Value == 0
                app.singlekSpace=app.kSpaceRest + noise;
            elseif app.ActivationCheckSingleKspace.Value == 1
                app.singlekSpace=app.kSpaceTask + noise;
            end

            reconstructionMethod = app.ReconstructionDropDown.Value;
            app.singleImage = eval(strcat(reconstructionMethod(1:end-2),'(app.MRI,app.kSpace,app.singlekSpace)'));


            if domain == 1 % k space
                if voxelData == 1 % magnitude
                    imagesc(app.singlekSpaceAxes,log10(abs(app.singlekSpace(:,:,coilN))+1))
                elseif voxelData == 2 % phase
                    imagesc(app.singlekSpaceAxes,angle(app.singlekSpace(:,:,coilN)))
                elseif voxelData == 3 % real
                    imagesc(app.singlekSpaceAxes,real(app.singlekSpace(:,:,coilN)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.singlekSpaceAxes,imag(app.singlekSpace(:,:,coilN)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.singlekSpaceAxes,mean(abs(app.singlekSpace),3))
                end
            elseif domain == 2 % image space
                if voxelData == 1 % magnitude
                    imagesc(app.singlekSpaceAxes,abs(app.singleImage(:,:,coilN)))
                elseif voxelData == 2 % phase
                    imagesc(app.singlekSpaceAxes,angle(app.singleImage(:,:,coilN)))
                elseif voxelData == 3 % real
                    imagesc(app.singlekSpaceAxes,real(app.singleImage(:,:,coilN)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.singlekSpaceAxes,imag(app.singleImage(:,:,coilN)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.singlekSpaceAxes,mean(abs(ifft2(ifftshift(app.singlekSpace))),3))
                end
            elseif domain == 3 % coils
                if voxelData == 1 % magnitude
                    imagesc(app.singlekSpaceAxes,abs(app.coilSensitivities(:,:,coilN)))
                elseif voxelData == 2 % phase
                    imagesc(app.singlekSpaceAxes,angle(app.coilSensitivities(:,:,coilN)))
                elseif voxelData == 3 % real
                    imagesc(app.singlekSpaceAxes,real(app.coilSensitivities(:,:,coilN)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.singlekSpaceAxes,imag(app.coilSensitivities(:,:,coilN)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.singlekSpaceAxes,mean(abs(app.coilSensitivities),3))
                end
            end
        
            if voxelData == 2
                colormap(app.singlekSpaceAxes,app.phaseColorMap)
            else
                colormap(app.singlekSpaceAxes,"gray")
            end


        end

        % Button pushed function: PlotDesignButton
        function PlotDesignButtonPushed(app, event)
            epochs = app.EpochsEditField.Value;
            initialRest = app.InitialRestImagesEditField.Value;
            restPerEpoch = app.RestImagesperEpochEditField.Value;
            taskPerEpoch = app.TaskImagesperEpochEditField.Value;
            app.design = cat(1,zeros(initialRest,1),repmat([ones(taskPerEpoch,1);zeros(restPerEpoch,1)],epochs,1));

            mn = min(app.design); mx = max(app.design);
            plot(app.designAxes,app.design,'black')
            app.designAxes.YTick = mn:(mx-mn):mx;
            app.designAxes.XTick = 0:length(app.design)/5:length(app.design);
            ylim(app.designAxes, [mn-0.1*(mn+1), mx+0.1*mx])
        end

        % Button pushed function: SimulatefMRIExperimentButton
        function SimulatefMRIExperimentButtonPushed(app, event)
            %%%%% REDUNDANT WITH PLOT DESIGN PUSHED... 3/1/24 %%%%
            epochs = app.EpochsEditField.Value;
            initialRest = app.InitialRestImagesEditField.Value;
            restPerEpoch = app.RestImagesperEpochEditField.Value;
            taskPerEpoch = app.TaskImagesperEpochEditField.Value;
            app.design = cat(1,zeros(initialRest,1),repmat([ones(taskPerEpoch,1);zeros(restPerEpoch,1)],epochs,1));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            app.singlekSpaceButtonPushed()
            app.kSpaceTimeSeries = generateTimeSeries(app.kSpaceRest,app.kSpaceTask,app.design,app.sigma);

            reconstructionMethod = app.ReconstructionDropDown.Value;
            app.imageSpaceTimeSeries = eval(strcat(reconstructionMethod(1:end-2),'(app.MRI,app.kSpace,app.kSpaceTimeSeries)'));
            %app.imageSpaceTimeSeries = ifftshift(ifft2(fftshift(app.kSpaceTimeSeries)),4);


            app.TimePointSpinner.Limits=[1,initialRest+epochs*(restPerEpoch+taskPerEpoch)];
            app.xEditField.Limits = [1,app.MRI.ReconstructionSize];
            app.yEditField.Limits = [1,app.MRI.ReconstructionSize];
            app.TimePointSpinner.Enable = 'on';

            
            app.updateTimeSeriesPlot()
            app.displaySummary()
        end

        % Value changed function: NumberofCoilsSpinner
        function NumberofCoilsSpinnerValueChanged(app, event)
            value = app.NumberofCoilsSpinner.Value;
            app.CoilNumberSpinner.Limits = [1,value];
            app.CoilNumberSpinner.Enable = 'on';
            app.CoilNumberSpinner_sample.Limits = [1,value];
            app.CoilNumberSpinner_sample.Enable = 'on';
        end

        % Value changed function: CoilNumberSpinner, DisplayVoxelCheckBox, 
        % ...and 5 other components
        function updateTimeSeriesPlot(app, event)
            voxelData = str2double(app.VoxelDataDropDown.Value);
            domain = app.DomainDropDown.Value;

            if app.design(app.TimePointSpinner.Value) == 1
                app.ActivationLamp.Color = [0,1,0];
            else
                app.ActivationLamp.Color = [1,0,0];
            end

            if domain == 1 % k space
                if voxelData == 1 % magnitude
                    imagesc(app.timeSeriesAxes,log10(abs(app.kSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value))+1))
                elseif voxelData == 2 % phase
                    imagesc(app.timeSeriesAxes,angle(app.kSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 3 % real
                    imagesc(app.timeSeriesAxes,real(app.kSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.timeSeriesAxes,imag(app.kSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.timeSeriesAxes,log10(mean(abs(app.kSpaceTimeSeries(:,:,:,app.TimePointSpinner.Value))+1,3)))
                end
            elseif domain == 2 % image space
                if voxelData == 1 % magnitude
                    imagesc(app.timeSeriesAxes,abs(app.imageSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 2 % phase
                    imagesc(app.timeSeriesAxes,angle(app.imageSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 3 % real
                    imagesc(app.timeSeriesAxes,real(app.imageSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 4 % imaginary
                    imagesc(app.timeSeriesAxes,imag(app.imageSpaceTimeSeries(:,:,app.CoilNumberSpinner.Value,app.TimePointSpinner.Value)))
                elseif voxelData == 5 % coil avg
                    imagesc(app.timeSeriesAxes,mean(abs(app.imageSpaceTimeSeries(:,:,:,app.TimePointSpinner.Value)),3))
                end
            end

            if voxelData == 2
                colormap(app.timeSeriesAxes,app.phaseColorMap)
            else
                colormap(app.timeSeriesAxes,"gray")
            end


            if app.DisplayVoxelCheckBox.Value == 1
                hold(app.timeSeriesAxes, 'on')
                scatter(app.timeSeriesAxes,app.xEditField.Value,app.MRI.ReconstructionSize - app.yEditField.Value,'red','filled')
                hold(app.timeSeriesAxes, 'off')
            end

        end

        % Button pushed function: VoxelTimeSeriesButton
        function VoxelTimeSeriesButtonPushed(app, event)
            figure('Name','Voxel Time Series')
            coil_avgd = squeeze(mean(app.imageSpaceTimeSeries,3));
            realV = squeeze(real(coil_avgd(app.MRI.ReconstructionSize - app.yEditField.Value + 1, app.xEditField.Value,:)));
            imagV = squeeze(imag(coil_avgd(app.MRI.ReconstructionSize - app.yEditField.Value + 1, app.xEditField.Value,:)));
            time = 1:size(coil_avgd,3);
            plot3(time,realV,imagV)         
            title(['Voxel time series, x=',num2str(app.xEditField.Value),' y=',num2str(app.yEditField.Value)]);
            xlabel('time'); ylabel('real'); zlabel('imaginary');
        end

        % Drop down opening function: StatisticDropDown
        function StatisticDropDownOpening(app, event)
            statisticsFiles = dir(fullfile('statisticsFunctions/', '*.m'));
            app.StatisticDropDown.Items =  {statisticsFiles.name}';
        end

        % Button pushed function: statsButton
        function statsButtonPushed(app, event)
            statisticsFunction = app.StatisticDropDown.Value;
            
            voxelData = str2double(app.StatsVoxelDataDropDown.Value);
            domain = app.StatsDomainDropDown.Value;

            if domain == 1 % k space
                if voxelData == 1 % magnitude
                    app.statisticsInputData = abs(app.kSpaceTimeSeries);
                elseif voxelData == 2 % phase
                    app.statisticsInputData = angle(app.kSpaceTimeSeries);
                elseif voxelData == 3 % real
                    app.statisticsInputData = real(app.kSpaceTimeSeries);
                elseif voxelData == 4 % imaginary
                    app.statisticsInputData = imag(app.kSpaceTimeSeries);
                end
            elseif domain == 2 % image space
                if voxelData == 1 % magnitude
                    app.statisticsInputData = abs(app.imageSpaceTimeSeries);
                elseif voxelData == 2 % phase
                    app.statisticsInputData = angle(app.imageSpaceTimeSeries);
                elseif voxelData == 3 % real
                    app.statisticsInputData = real(app.imageSpaceTimeSeries);
                elseif voxelData == 4 % imaginary
                    app.statisticsInputData = imag(app.imageSpaceTimeSeries);
                end
            end


            app.statisticsData = eval(strcat(statisticsFunction(1:end-2),'(app.statisticsInputData,app.design,app.MRI)'));
            
            app.updateStatisticsPlot()
        end

        % Button pushed function: VoxelHistogramButton
        function VoxelHistogramButtonPushed(app, event)
            colorbar(app.statisticsAxes,'off')
            xticks(app.statisticsAxes,'auto')
            yticks(app.statisticsAxes,'auto')
            app.statisticsAxes.DataAspectRatioMode = 'auto';
            app.statisticsAxes.XDir = 'normal';
            app.statisticsAxes.YDir = 'normal';
           
           
            voxelData = str2double(app.StatsVoxelDataDropDown.Value);
            domain = app.StatsDomainDropDown.Value;


            if domain == 1 % k space
                coil_avgd_kspace = squeeze(mean(app.kSpaceTimeSeries,3));
                voxelTS_kspace = squeeze(coil_avgd_kspace(app.MRI.ReconstructionSize - app.yEditField_2.Value + 1, app.xEditField_2.Value,:));

                thetaBar_kspace = circ_mean(angle(voxelTS_kspace));
                voxelTS_kspace_phaseCorrected = voxelTS_kspace * exp(-sqrt(-1)*thetaBar_kspace);
                kspace_Nu = mean(real(voxelTS_kspace_phaseCorrected)) / cos(circ_mean(angle(voxelTS_kspace)));
                kspace_s = sqrt(mean([var(real(voxelTS_kspace_phaseCorrected)),var(imag(voxelTS_kspace_phaseCorrected))]));

                if voxelData == (1||5) % magnitude
                    app.myHist = histogram(app.statisticsAxes,abs(voxelTS_kspace),'FaceColor','blue','FaceAlpha',0.2,'Normalization','pdf',EdgeAlpha=0.2,NumBins=18);
                    pd = makedist('Rician','s',abs(kspace_Nu),'sigma',kspace_s);
                    x=linspace(min(abs(voxelTS_kspace),[],'all'),max(abs(voxelTS_kspace),[],'all'),100);
                    hold(app.statisticsAxes,'on');
                    plot(app.statisticsAxes,x,pd.pdf(x),'k--','LineWidth',2)
                    hold(app.statisticsAxes,'off');
                elseif voxelData == 2 % phase
                    app.myHist = histogram(app.statisticsAxes,angle(voxelTS_kspace),'FaceColor','blue','FaceAlpha',0.2,'Normalization','pdf',EdgeAlpha=0.2,NumBins=18);
                    unnnpdf =@(phi, rho, theta, s2) 1/(2*pi) * exp(- rho.^2 ./ (2*s2)) .*...
                                (1 + rho./(sqrt(s2)) .* sqrt(2*pi) .* cos(phi-theta) .*...
                                exp((rho.^2 .* (cos(phi-theta)).^2)./(2*s2)) .*...
                                normcdf((rho.*cos(phi-theta))./sqrt(s2)));
                    x=linspace(-pi,pi,100);
                    hold(app.statisticsAxes,'on');
                    plot(app.statisticsAxes,x,unnnpdf(x, kspace_Nu, thetaBar_kspace, kspace_s^2),'k--','LineWidth',2)
                    hold(app.statisticsAxes,'off');
                elseif voxelData == 3 % real
                    app.myHist = histogram(app.statisticsAxes,real(voxelTS_kspace),'FaceColor','blue','FaceAlpha',0.2,'Normalization','pdf',EdgeAlpha=0.2,NumBins=18);
                    x=linspace(min(real(voxelTS_kspace),[],'all'),max(real(voxelTS_kspace),[],'all'),100);
                    hold(app.statisticsAxes,'on');
                    plot(app.statisticsAxes,x,normpdf(x,mean(real(voxelTS_kspace)),kspace_s),'k--','LineWidth',2)
                    hold(app.statisticsAxes,'off');
                elseif voxelData == 4 % imaginary
                    app.myHist = histogram(app.statisticsAxes,imag(voxelTS_kspace),'FaceColor','blue','FaceAlpha',0.2,'Normalization','pdf',EdgeAlpha=0.2,NumBins=18);
                    x=linspace(min(imag(voxelTS_kspace),[],'all'),max(imag(voxelTS_kspace),[],'all'),100);
                    hold(app.statisticsAxes,'on');
                    plot(app.statisticsAxes,x,normpdf(x,mean(imag(voxelTS_kspace)),kspace_s),'k--','LineWidth',2)
                    hold(app.statisticsAxes,'off');
                %elseif voxelData == 5 % coil avg
                %    imagesc(app.timeSeriesAxes,log10(mean(abs(app.kSpaceTimeSeries(:,:,:,app.TimePointSpinner.Value))+1,3)))
                end
            elseif domain == 2 % image space
                coil_avgd_image = squeeze(mean(app.imageSpaceTimeSeries,3));
                voxelTS_image = squeeze(coil_avgd_image(app.MRI.ReconstructionSize - app.yEditField_2.Value + 1, app.xEditField_2.Value,:));
                
                thetaBar_image = circ_mean(angle(voxelTS_image));
                voxelTS_image_phaseCorrected = voxelTS_image * exp(-sqrt(-1)*thetaBar_image);
                image_Nu = mean(real(voxelTS_image_phaseCorrected)) / cos(circ_mean(angle(voxelTS_image)));
                image_s = sqrt(mean([var(real(voxelTS_image_phaseCorrected)),var(imag(voxelTS_image_phaseCorrected))]));

                if voxelData == (1||5) % magnitude
                    app.myHist = histogram(app.statisticsAxes,abs(voxelTS_image),'FaceColor','blue','FaceAlpha',0.2,'Normalization','pdf',EdgeAlpha=0.2,NumBins=18);
                    pd = makedist('Rician','s',abs(image_Nu),'sigma',image_s);
                    x=linspace(min(abs(voxelTS_image),[],'all'),max(abs(voxelTS_image),[],'all'),100);
                    hold(app.statisticsAxes,'on');
                    plot(app.statisticsAxes,x,pd.pdf(x),'k--','LineWidth',2)
                    hold(app.statisticsAxes,'off');
                elseif voxelData == 2 % phase
                    app.myHist = histogram(app.statisticsAxes,angle(voxelTS_image),'FaceColor','blue','FaceAlpha',0.2,'Normalization','pdf',EdgeAlpha=0.2,NumBins=18);
                    unnnpdf =@(phi, rho, theta, s2) 1/(2*pi) * exp(- rho.^2 ./ (2*s2)) .*...
                                (1 + rho./(sqrt(s2)) .* sqrt(2*pi) .* cos(phi-theta) .*...
                                exp((rho.^2 .* (cos(phi-theta)).^2)./(2*s2)) .*...
                                normcdf((rho.*cos(phi-theta))./sqrt(s2)));
                    x=linspace(-pi,pi,100);
                    hold(app.statisticsAxes,'on');
                    plot(app.statisticsAxes,x,unnnpdf(x, image_Nu, thetaBar_image, image_s^2),'k--','LineWidth',2)
                    hold(app.statisticsAxes,'off');
                elseif voxelData == 3 % real
                    app.myHist = histogram(app.statisticsAxes,real(voxelTS_image),'FaceColor','blue','FaceAlpha',0.2,'Normalization','pdf',EdgeAlpha=0.2,NumBins=18);
                    x=linspace(min(real(voxelTS_image),[],'all'),max(real(voxelTS_image),[],'all'),100);
                    hold(app.statisticsAxes,'on');
                    plot(app.statisticsAxes,x,normpdf(x,mean(real(voxelTS_image)),image_s),'k--','LineWidth',2)
                    hold(app.statisticsAxes,'off');
                elseif voxelData == 4 % imaginary
                    app.myHist = histogram(app.statisticsAxes,imag(voxelTS_image),'FaceColor','blue','FaceAlpha',0.2,'Normalization','pdf',EdgeAlpha=0.2,NumBins=18);
                    x=linspace(min(imag(voxelTS_image),[],'all'),max(imag(voxelTS_image),[],'all'),100);
                    hold(app.statisticsAxes,'on');
                    plot(app.statisticsAxes,x,normpdf(x,mean(imag(voxelTS_image)),image_s),'k--','LineWidth',2)
                    hold(app.statisticsAxes,'off');
                %elseif voxelData == 5 % coil avg
                %    imagesc(app.timeSeriesAxes,mean(abs(app.imageSpaceTimeSeries(:,:,:,app.TimePointSpinner.Value)),3))
                end
            end

            %{
            x=linspace(min(abs(voxelTS_image),[],'all'),max(abs(voxelTS_image),[],'all'),100);
            tmps2alt = var(abs(voxelTS_image));
            hold(app.statisticsAxes,'on');
            plot(app.statisticsAxes,x,raylpdf(x,sqrt(tmps2alt*2/(4-pi))),'k--','LineWidth',2)
            hold(app.statisticsAxes,'off');
            %}
        end

        % Value changed function: MaskEmptySpaceCheckBox, 
        % ...and 1 other component
        function updateStatisticsPlot(app, event)
            if isempty(app.statisticsData)
                return
            end
            
            
            if app.MaskEmptySpaceCheckBox.Value == 1
                imagesc(app.statisticsAxes,app.statisticsData .* app.MASK)
                colormap(app.statisticsAxes,"hot")
                colorbar(app.statisticsAxes)
            elseif app.MaskEmptySpaceCheckBox.Value == 0
                imagesc(app.statisticsAxes,app.statisticsData)
                colormap(app.statisticsAxes,"hot")
                colorbar(app.statisticsAxes)
            end


            if app.SuperimposeAnatomicalCheckBox.Value == 1
                xdimf=app.MRI.ReconstructionSize; ydimf=app.MRI.ReconstructionSize;
                xinc=xdimf/4; yinc=ydimf/4;
                graylow=min(app.Anatomical,[],'all'); grayhigh=max(app.Anatomical,[],'all');
                threshval=0.4*max(app.statisticsData,[],'all'); maxval=1.1*max(app.statisticsData,[],'all'); 


                image(app.statisticsAxes,makeimagecorr(app.Anatomical,graylow,grayhigh,app.statisticsData,threshval,maxval))
                colormap(app.statisticsAxes,app.superimposeColorMap)
                %colorbar(app.statisticsAxes,'off')
                app.statisticsAxes.XTick = 0:xinc:xdimf;
                app.statisticsAxes.YTick = 0:yinc:ydimf;
                
            end
            app.statisticsAxes.DataAspectRatio = [1 1 1];
        end

        % Callback function: ExportSingleKSpaceButton, singlekspace
        function ExportSingleKSpaceButtonPushed(app, event)
            ks = app.kSpace;
            uisave('ks','SinglekSpace.mat')
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Callback function: ExportTimeSeriesButton, TimeSerieskSpaceMenu
        function ExportTimeSeriesButtonPushed(app, event)
            ksTS = app.kSpaceTimeSeries;
            uisave('ksTS','kSpaceTimeSeries.mat')
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Menu selected function: ExportMRIStruct
        function ExportMRIStructMenuSelected(app, event)
            % update MRI structure
            updateMRIStruct(app);
            MRI = app.MRI;
            % export it
            uisave('MRI','MRI_Parameters.mat')
            figure(app.UIFigure); % returns UI to focus... annyoing workaround for unfocusing bug on uimport/export
        end

        % Value changed function: RNGseedEditField
        function RNGseedEditFieldValueChanged(app, event)
            value = app.RNGseedEditField.Value;
            rng(value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.MapsMenu = uimenu(app.ImportMenu);
            app.MapsMenu.Text = 'Maps';
            app.M0FileMenu = uimenu(app.MapsMenu);
            app.M0FileMenu.MenuSelectedFcn = createCallbackFcn(app, @M0FileMenuSelected, true);
            app.M0FileMenu.Text = 'M0 File';
            app.T1FileMenu = uimenu(app.MapsMenu);
            app.T1FileMenu.MenuSelectedFcn = createCallbackFcn(app, @T1FileMenuSelected, true);
            app.T1FileMenu.Text = 'T1 File';
            app.T2FileMenu = uimenu(app.MapsMenu);
            app.T2FileMenu.MenuSelectedFcn = createCallbackFcn(app, @T2FileMenuSelected, true);
            app.T2FileMenu.Text = 'T2 File';
            app.deltaBFileMenu = uimenu(app.MapsMenu);
            app.deltaBFileMenu.MenuSelectedFcn = createCallbackFcn(app, @deltaBFileMenuSelected, true);
            app.deltaBFileMenu.Text = 'delta B File';
            app.ActivationFileMenu = uimenu(app.MapsMenu);
            app.ActivationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ActivationFileMenuSelected, true);
            app.ActivationFileMenu.Text = 'Activation File';
            app.ImportMRIStruct = uimenu(app.ImportMenu);
            app.ImportMRIStruct.MenuSelectedFcn = createCallbackFcn(app, @ImportMRIStructMenuSelected, true);
            app.ImportMRIStruct.Text = 'MRI Parameters';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.singlekspace = uimenu(app.ExportMenu);
            app.singlekspace.MenuSelectedFcn = createCallbackFcn(app, @ExportSingleKSpaceButtonPushed, true);
            app.singlekspace.Text = 'Single k-space';
            app.TimeSerieskSpaceMenu = uimenu(app.ExportMenu);
            app.TimeSerieskSpaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportTimeSeriesButtonPushed, true);
            app.TimeSerieskSpaceMenu.Text = 'Time Series k-Space';
            app.ExportMRIStruct = uimenu(app.ExportMenu);
            app.ExportMRIStruct.MenuSelectedFcn = createCallbackFcn(app, @ExportMRIStructMenuSelected, true);
            app.ExportMRIStruct.Text = 'MRI Parameters';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.MRIParametersTab = uitab(app.TabGroup);
            app.MRIParametersTab.Title = 'MRI Parameters';
            app.singlekSpaceAxes = uiaxes(app.MRIParametersTab);
            app.singlekSpaceAxes.XTick = [];
            app.singlekSpaceAxes.XTickLabel = '';
            app.singlekSpaceAxes.YTick = [];
            app.singlekSpaceAxes.YTickLabel = '';
            colormap(app.singlekSpaceAxes, 'gray')
            app.singlekSpaceButton = uibutton(app.MRIParametersTab, 'push');
            app.singlekSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @singlekSpaceButtonPushed, true);
            app.singlekSpaceButton.Text = 'Generate Single kSpace';
            app.ActivationCheckSingleKspace = uicheckbox(app.MRIParametersTab);
            app.ActivationCheckSingleKspace.ValueChangedFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            app.ActivationCheckSingleKspace.Text = 'Show Activated Slice';
            app.CoilNumberSpinner_2Label = uilabel(app.MRIParametersTab);
            app.CoilNumberSpinner_2Label.Text = 'Coil Number';
            app.CoilNumberSpinner_sample = uispinner(app.MRIParametersTab);
            app.CoilNumberSpinner_sample.Limits = [1 Inf];
            app.CoilNumberSpinner_sample.RoundFractionalValues = 'on';
            app.CoilNumberSpinner_sample.ValueChangedFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            app.CoilNumberSpinner_sample.Enable = 'off';
            app.CoilNumberSpinner_sample.Value = 1;
            app.VoxelDataDropDown_sample = uidropdown(app.MRIParametersTab);
            app.VoxelDataDropDown_sample.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary', 'Coil avg (mag)'};
            app.VoxelDataDropDown_sample.ItemsData = {'1', '2', '3', '4', '5'};
            app.VoxelDataDropDown_sample.ValueChangedFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            app.VoxelDataDropDown_sample.Value = '1';
            app.DomainDropDown_sample = uidropdown(app.MRIParametersTab);
            app.DomainDropDown_sample.Items = {'kSpace', 'Image Space', 'Coil Sensitivities'};
            app.DomainDropDown_sample.ItemsData = {'1', '2', '3'};
            app.DomainDropDown_sample.ValueChangedFcn = createCallbackFcn(app, @updateSingleKspacePlot, true);
            app.DomainDropDown_sample.Value = '1';
            app.SignalEquationDropdown = uidropdown(app.MRIParametersTab);
            app.SignalEquationDropdown.Items = {};
            app.SignalEquationDropdown.DropDownOpeningFcn = createCallbackFcn(app, @SignalEquationDropdownDropDownOpening, true);
            app.SignalEquationDropdown.ValueChangedFcn = createCallbackFcn(app, @SignalEquationDropdownValueChanged, true);
            app.SignalEquationDropdown.Placeholder = 'Select';
            app.SignalEquationDropdown.Value = {};
            app.ViewLabel_2 = uilabel(app.MRIParametersTab);
            app.ViewLabel_2.Text = 'Signal Equation';
            app.TrajectoryDropDown = uidropdown(app.MRIParametersTab);
            app.TrajectoryDropDown.Items = {};
            app.TrajectoryDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TrajectoryDropDownOpening, true);
            app.TrajectoryDropDown.Placeholder = 'Select';
            app.TrajectoryDropDown.Value = {};
            app.TrajectoryDropDownLabel = uilabel(app.MRIParametersTab);
            app.TrajectoryDropDownLabel.Text = 'Trajectory';
            app.AccelerationFactorSpinner = uispinner(app.MRIParametersTab);
            app.AccelerationFactorSpinner.Limits = [1 8];
            app.AccelerationFactorSpinner.Tooltip = {'Rate at which kspace data is subsampled'};
            app.AccelerationFactorSpinner.Value = 1;
            app.AccelerationFactorLabel = uilabel(app.MRIParametersTab);
            app.AccelerationFactorLabel.Text = 'Acceleration Factor';
            app.FieldStrengthTEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.FieldStrengthTEditField.Limits = [0 Inf];
            app.FieldStrengthTEditField.Value = 3;
            app.FieldStrengthTEditFieldLabel = uilabel(app.MRIParametersTab);
            app.FieldStrengthTEditFieldLabel.Text = 'Field Strength (T)';
            app.TEmsEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.TEmsEditField.Limits = [0 Inf];
            app.TEmsEditField.Tooltip = {'Echo Time'};
            app.TEmsEditField.Value = 50;
            app.TEmsEditFieldLabel = uilabel(app.MRIParametersTab);
            app.TEmsEditFieldLabel.Text = 'TE (ms)';
            app.TRmsEditFieldLabel = uilabel(app.MRIParametersTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRmsEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.TRmsEditField.Limits = [0 Inf];
            app.TRmsEditField.Tooltip = {'Repitition Time'};
            app.TRmsEditField.Value = 1000;
            app.FlipAngledegEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.FlipAngledegEditField.Limits = [0 Inf];
            app.FlipAngledegEditField.Value = 90;
            app.FlipAngledegEditFieldLabel = uilabel(app.MRIParametersTab);
            app.FlipAngledegEditFieldLabel.Text = 'Flip Angle (deg)';
            app.NumberofCoilsSpinnerLabel = uilabel(app.MRIParametersTab);
            app.NumberofCoilsSpinnerLabel.Text = 'Number of Coils';
            app.NumberofCoilsSpinner = uispinner(app.MRIParametersTab);
            app.NumberofCoilsSpinner.Limits = [1 Inf];
            app.NumberofCoilsSpinner.RoundFractionalValues = 'on';
            app.NumberofCoilsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofCoilsSpinnerValueChanged, true);
            app.NumberofCoilsSpinner.Tooltip = {'Radially symmetric coil array'};
            app.NumberofCoilsSpinner.Value = 1;
            app.VoxelDataDropDown_2Label = uilabel(app.MRIParametersTab);
            app.VoxelDataDropDown_2Label.Text = 'Voxel Data';
            app.DomainDropDown_2Label = uilabel(app.MRIParametersTab);
            app.DomainDropDown_2Label.Text = 'Domain';
            app.ExportSingleKSpaceButton = uibutton(app.MRIParametersTab, 'push');
            app.ExportSingleKSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSingleKSpaceButtonPushed, true);
            app.ExportSingleKSpaceButton.Text = 'Export Single k-Space';
            app.WMGMCSFLabel = uilabel(app.MRIParametersTab);
            app.WMGMCSFLabel.Text = {'WM'; 'GM'; 'CSF'};
            app.RelaxivityTable = uitable(app.MRIParametersTab);
            app.RelaxivityTable.ColumnName = {'M0'; 'T1 (ms)'; 'T2* (ms)'};
            app.RelaxivityTable.RowName = {};
            app.RelaxivityTable.ColumnEditable = [true true true];
            app.RelaxivityTable.Tooltip = {'Gray Matter, White Matter, Cerebral Spinal Fluid'};
            app.deltaB0Check = uicheckbox(app.MRIParametersTab);
            app.deltaB0Check.Text = 'Include B0 Inhomogeneity';
            app.deltaB0Check.Value = true;
            app.ReconstructionDropDown = uidropdown(app.MRIParametersTab);
            app.ReconstructionDropDown.Items = {};
            app.ReconstructionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ReconstructionDropDownOpening, true);
            app.ReconstructionDropDown.Placeholder = 'Select';
            app.ReconstructionDropDown.Value = {};
            app.ReconstructionDropDownLabel = uilabel(app.MRIParametersTab);
            app.ReconstructionDropDownLabel.Text = 'Reconstruction';
            app.EESPmsEditField = uieditfield(app.MRIParametersTab, 'numeric');
            app.EESPmsEditField.Limits = [0 Inf];
            app.EESPmsEditField.Tooltip = {'Echo Time'};
            app.EESPmsEditField.Value = 0.72;
            app.EESPmsEditFieldLabel = uilabel(app.MRIParametersTab);
            app.EESPmsEditFieldLabel.Text = 'EESP (ms)';
            app.TaskDesignTab = uitab(app.TabGroup);
            app.TaskDesignTab.Title = 'Task Design';
            app.designAxes = uiaxes(app.TaskDesignTab);
            xlabel(app.designAxes, 'Time')
            app.HRFDropDownLabel = uilabel(app.TaskDesignTab);
            app.HRFDropDownLabel.Text = 'HRF';
            app.HRFDropDown = uidropdown(app.TaskDesignTab);
            app.HRFDropDown.Items = {'Block'};
            app.HRFDropDown.Tooltip = {'Haemodynamic Response Function'};
            app.HRFDropDown.Value = 'Block';
            app.EpochsEditFieldLabel = uilabel(app.TaskDesignTab);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.TaskDesignTab, 'numeric');
            app.EpochsEditField.Limits = [1 Inf];
            app.EpochsEditField.RoundFractionalValues = 'on';
            app.EpochsEditField.Tooltip = {'Repititions of task activation'};
            app.EpochsEditField.Value = 20;
            app.RestImagesperEpochEditFieldLabel = uilabel(app.TaskDesignTab);
            app.RestImagesperEpochEditFieldLabel.Text = 'Rest Images per Epoch';
            app.RestImagesperEpochEditField = uieditfield(app.TaskDesignTab, 'numeric');
            app.RestImagesperEpochEditField.Limits = [0 Inf];
            app.RestImagesperEpochEditField.RoundFractionalValues = 'on';
            app.RestImagesperEpochEditField.Tooltip = {'per epoch'};
            app.RestImagesperEpochEditField.Value = 15;
            app.TaskImagesperEpochEditFieldLabel = uilabel(app.TaskDesignTab);
            app.TaskImagesperEpochEditFieldLabel.Text = 'Task Images per Epoch';
            app.TaskImagesperEpochEditField = uieditfield(app.TaskDesignTab, 'numeric');
            app.TaskImagesperEpochEditField.Limits = [0 Inf];
            app.TaskImagesperEpochEditField.RoundFractionalValues = 'on';
            app.TaskImagesperEpochEditField.Tooltip = {'per epoch'};
            app.TaskImagesperEpochEditField.Value = 15;
            app.PlotDesignButton = uibutton(app.TaskDesignTab, 'push');
            app.PlotDesignButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDesignButtonPushed, true);
            app.PlotDesignButton.Text = 'Plot Design';
            app.SimulatefMRIExperimentButton = uibutton(app.TaskDesignTab, 'push');
            app.SimulatefMRIExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SimulatefMRIExperimentButtonPushed, true);
            app.SimulatefMRIExperimentButton.Text = 'Simulate fMRI Experiment';
            app.TabGroup2 = uitabgroup(app.TaskDesignTab);
            app.Task1Tab = uitab(app.TabGroup2);
            app.Task1Tab.Title = 'Task 1';
            app.ActivationLabel = uilabel(app.Task1Tab);
            app.ActivationLabel.Enable = 'off';
            app.ActivationLabel.Visible = 'off';
            app.ActivationLabel.Text = 'Magnitude';
            app.ActivationMag = uieditfield(app.Task1Tab, 'numeric');
            app.ActivationMag.Editable = 'off';
            app.ActivationMag.Enable = 'off';
            app.ActivationMag.Visible = 'off';
            app.ActivationMag.Tooltip = {'Given as percentage of the maximum voxel value in the non-task image'};
            app.ActivationMag.Value = 0.02;
            app.PhaseIncreaseLabel = uilabel(app.Task1Tab);
            app.PhaseIncreaseLabel.Text = 'Phase (deg)';
            app.ActivationPhase = uieditfield(app.Task1Tab, 'numeric');
            app.ActivationPhase.Tooltip = {'Angular increase of the phase in the activated ROI'};
            app.CNREditFieldLabel = uilabel(app.Task1Tab);
            app.CNREditFieldLabel.Text = 'CNR';
            app.CNREditField = uieditfield(app.Task1Tab, 'numeric');
            app.CNREditField.Limits = [0 Inf];
            app.CNREditField.Tooltip = {'Contrast-to-Noise Ratio, Beta1/sigma'};
            app.CNREditField.Value = 0.75;
            app.Task2Tab = uitab(app.TabGroup2);
            app.Task2Tab.Title = 'Task 2';
            app.InitialRestImagesEditFieldLabel = uilabel(app.TaskDesignTab);
            app.InitialRestImagesEditFieldLabel.Text = 'Initial Rest Images';
            app.InitialRestImagesEditField = uieditfield(app.TaskDesignTab, 'numeric');
            app.InitialRestImagesEditField.Limits = [0 Inf];
            app.InitialRestImagesEditField.RoundFractionalValues = 'on';
            app.InitialRestImagesEditField.Tooltip = {'per epoch'};
            app.InitialRestImagesEditField.Value = 10;
            app.SNREditFieldLabel = uilabel(app.TaskDesignTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.TaskDesignTab, 'numeric');
            app.SNREditField.Limits = [0 Inf];
            app.SNREditField.Tooltip = {'Signal-to-Noise Ratio, Beta0/sigma'};
            app.SNREditField.Value = 5;
            app.DataViewerPanel = uipanel(app.LeftPanel);
            app.DataViewerPanel.Title = 'Data Viewer';
            app.MapAxes = uiaxes(app.DataViewerPanel);
            app.MapAxes.XTick = [];
            app.MapAxes.XTickLabel = '';
            app.MapAxes.YTick = [];
            app.MapAxes.ButtonDownFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.PlotActivationCheck = uicheckbox(app.DataViewerPanel);
            app.PlotActivationCheck.ValueChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.PlotActivationCheck.Text = 'Overlay Activation';
            app.SliceSpinner = uispinner(app.DataViewerPanel);
            app.SliceSpinner.Limits = [1 96];
            app.SliceSpinner.RoundFractionalValues = 'on';
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.SliceSpinner.Value = 48;
            app.SliceLabel = uilabel(app.DataViewerPanel);
            app.SliceLabel.Text = 'Slice';
            app.SliceViewDropDown = uidropdown(app.DataViewerPanel);
            app.SliceViewDropDown.Items = {'Axial', 'Sagital', 'Coronal'};
            app.SliceViewDropDown.ItemsData = {'1', '2', '3'};
            app.SliceViewDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.SliceViewDropDown.Value = '1';
            app.ViewLabel = uilabel(app.DataViewerPanel);
            app.ViewLabel.Text = 'View';
            app.MapDropDownLabel = uilabel(app.DataViewerPanel);
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.DataViewerPanel);
            app.MapDropDown.Items = {'M0', 'T1', 'T2*', 'delta B'};
            app.MapDropDown.ItemsData = [1 2 3 4];
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.MapDropDown.Value = 1;
            app.DViewButton = uibutton(app.DataViewerPanel, 'push');
            app.DViewButton.ButtonPushedFcn = createCallbackFcn(app, @threeDViewButtonPushed, true);
            app.DViewButton.Text = '3D View';
            app.PhantomSizeButtonGroup = uibuttongroup(app.DataViewerPanel);
            app.PhantomSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateMapsPlot, true);
            app.PhantomSizeButtonGroup.Title = 'Phantom Size';
            app.phantomSize64 = uiradiobutton(app.PhantomSizeButtonGroup);
            app.phantomSize64.Text = '64';
            app.phantomSize96 = uiradiobutton(app.PhantomSizeButtonGroup);
            app.phantomSize96.Text = '96';
            app.phantomSize96.Value = true;
            app.phantomSize128 = uiradiobutton(app.PhantomSizeButtonGroup);
            app.phantomSize128.Text = '128';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TimeSeriesPanel = uipanel(app.CenterPanel);
            app.TimeSeriesPanel.Title = 'Time Series';
            app.timeSeriesAxes = uiaxes(app.TimeSeriesPanel);
            app.timeSeriesAxes.XTick = [];
            app.timeSeriesAxes.YTick = [];
            colormap(app.timeSeriesAxes, 'gray')
            app.VoxelTimeSeriesButton = uibutton(app.TimeSeriesPanel, 'push');
            app.VoxelTimeSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @VoxelTimeSeriesButtonPushed, true);
            app.VoxelTimeSeriesButton.Text = 'Voxel Time Series';
            app.ExportTimeSeriesButton = uibutton(app.TimeSeriesPanel, 'push');
            app.ExportTimeSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTimeSeriesButtonPushed, true);
            app.ExportTimeSeriesButton.Text = 'Export Time Series';
            app.TimePointSpinnerLabel = uilabel(app.TimeSeriesPanel);
            app.TimePointSpinnerLabel.Text = 'Time Point';
            app.TimePointSpinner = uispinner(app.TimeSeriesPanel);
            app.TimePointSpinner.Limits = [1 Inf];
            app.TimePointSpinner.RoundFractionalValues = 'on';
            app.TimePointSpinner.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.TimePointSpinner.Enable = 'off';
            app.TimePointSpinner.Value = 1;
            app.CoilNumberSpinnerLabel = uilabel(app.TimeSeriesPanel);
            app.CoilNumberSpinnerLabel.Text = 'Coil Number';
            app.CoilNumberSpinner = uispinner(app.TimeSeriesPanel);
            app.CoilNumberSpinner.Limits = [1 Inf];
            app.CoilNumberSpinner.RoundFractionalValues = 'on';
            app.CoilNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.CoilNumberSpinner.Enable = 'off';
            app.CoilNumberSpinner.Value = 1;
            app.DomainDropDownLabel = uilabel(app.TimeSeriesPanel);
            app.DomainDropDownLabel.Text = 'Domain';
            app.DomainDropDown = uidropdown(app.TimeSeriesPanel);
            app.DomainDropDown.Items = {'kSpace', 'Image Space'};
            app.DomainDropDown.ItemsData = [1 2];
            app.DomainDropDown.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.DomainDropDown.Value = 1;
            app.VoxelDataDropDownLabel = uilabel(app.TimeSeriesPanel);
            app.VoxelDataDropDownLabel.Text = 'Voxel Data';
            app.VoxelDataDropDown = uidropdown(app.TimeSeriesPanel);
            app.VoxelDataDropDown.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary', 'Coil Avg (mag)'};
            app.VoxelDataDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.VoxelDataDropDown.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.VoxelDataDropDown.Value = '1';
            app.ActivationLamp = uilamp(app.TimeSeriesPanel);
            app.ActivationLamp.Tooltip = {'Red if in rest state, grreen if in task state'};
            app.ActivationLabel_2 = uilabel(app.TimeSeriesPanel);
            app.ActivationLabel_2.Text = 'Activation';
            app.xEditFieldLabel = uilabel(app.TimeSeriesPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.TimeSeriesPanel, 'numeric');
            app.xEditField.Limits = [1 Inf];
            app.xEditField.RoundFractionalValues = 'on';
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.xEditField.Value = 44;
            app.yEditFieldLabel = uilabel(app.TimeSeriesPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.TimeSeriesPanel, 'numeric');
            app.yEditField.Limits = [1 Inf];
            app.yEditField.RoundFractionalValues = 'on';
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.yEditField.Value = 33;
            app.DisplayVoxelCheckBox = uicheckbox(app.TimeSeriesPanel);
            app.DisplayVoxelCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateTimeSeriesPlot, true);
            app.DisplayVoxelCheckBox.Text = 'Display Voxel';
            app.StatisticalAnalysisPanel = uipanel(app.CenterPanel);
            app.StatisticalAnalysisPanel.Title = 'Statistical Analysis';
            app.statisticsAxes = uiaxes(app.StatisticalAnalysisPanel);
            colormap(app.statisticsAxes, 'gray')
            app.statsButton = uibutton(app.StatisticalAnalysisPanel, 'push');
            app.statsButton.ButtonPushedFcn = createCallbackFcn(app, @statsButtonPushed, true);
            app.statsButton.Tooltip = {'View t-statistic map of mean activation v mean rest images'};
            app.statsButton.Text = 'Calculate Statistic';
            app.SuperimposeAnatomicalCheckBox = uicheckbox(app.StatisticalAnalysisPanel);
            app.SuperimposeAnatomicalCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateStatisticsPlot, true);
            app.SuperimposeAnatomicalCheckBox.Text = 'Superimpose Anatomical';
            app.MaskEmptySpaceCheckBox = uicheckbox(app.StatisticalAnalysisPanel);
            app.MaskEmptySpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateStatisticsPlot, true);
            app.MaskEmptySpaceCheckBox.Text = 'Mask Empty Space';
            app.StatisticDropDownLabel = uilabel(app.StatisticalAnalysisPanel);
            app.StatisticDropDownLabel.Text = 'Statistic';
            app.StatisticDropDown = uidropdown(app.StatisticalAnalysisPanel);
            app.StatisticDropDown.Items = {};
            app.StatisticDropDown.DropDownOpeningFcn = createCallbackFcn(app, @StatisticDropDownOpening, true);
            app.StatisticDropDown.Placeholder = 'Select';
            app.StatisticDropDown.Value = {};
            app.StatsDomainDropDown = uidropdown(app.StatisticalAnalysisPanel);
            app.StatsDomainDropDown.Items = {'kSpace', 'Image Space'};
            app.StatsDomainDropDown.ItemsData = [1 2];
            app.StatsDomainDropDown.Value = 1;
            app.DomainDropDown_2Label_2 = uilabel(app.StatisticalAnalysisPanel);
            app.DomainDropDown_2Label_2.Text = 'Domain';
            app.StatsVoxelDataDropDown = uidropdown(app.StatisticalAnalysisPanel);
            app.StatsVoxelDataDropDown.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary'};
            app.StatsVoxelDataDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.StatsVoxelDataDropDown.Value = '1';
            app.VoxelDataDropDown_2Label_2 = uilabel(app.StatisticalAnalysisPanel);
            app.VoxelDataDropDown_2Label_2.Text = 'Voxel Data';
            app.Image_2 = uiimage(app.StatisticalAnalysisPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'data', 'Marquette_mu_small.png');
            app.SHAKERMarquetteUniversityBodenschatzRowe2025Label = uilabel(app.StatisticalAnalysisPanel);
            app.SHAKERMarquetteUniversityBodenschatzRowe2025Label.Tooltip = {'Simulation and Harmonic Analysis of K-spacE Readout'};
            app.SHAKERMarquetteUniversityBodenschatzRowe2025Label.Text = {'SHAKER                 '; 'Marquette University'; 'Bodenschatz, Rowe, 2025'};
            app.Hyperlink_2 = uihyperlink(app.StatisticalAnalysisPanel);
            app.Hyperlink_2.URL = 'https://github.com/bodensjc/kspace-simulator';
            app.Hyperlink_2.Text = 'GitHub';
            app.xEditField_2Label = uilabel(app.StatisticalAnalysisPanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.StatisticalAnalysisPanel, 'numeric');
            app.xEditField_2.Limits = [1 Inf];
            app.xEditField_2.RoundFractionalValues = 'on';
            app.xEditField_2.Value = 44;
            app.yEditField_2Label = uilabel(app.StatisticalAnalysisPanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.StatisticalAnalysisPanel, 'numeric');
            app.yEditField_2.Limits = [1 Inf];
            app.yEditField_2.RoundFractionalValues = 'on';
            app.yEditField_2.Value = 33;
            app.VoxelHistogramButton = uibutton(app.StatisticalAnalysisPanel, 'push');
            app.VoxelHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @VoxelHistogramButtonPushed, true);
            app.VoxelHistogramButton.Text = 'Voxel Histogram';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextOutput = uitextarea(app.RightPanel);
            app.TextOutput.Editable = 'off';
            app.TextOutput.Value = {'SHAKER'; 'Simulation and Harmonic Analysis of k-Space Readout'};
            app.RNGseedEditFieldLabel = uilabel(app.RightPanel);
            app.RNGseedEditFieldLabel.Text = 'RNG seed';
            app.RNGseedEditField = uieditfield(app.RightPanel, 'numeric');
            app.RNGseedEditField.ValueChangedFcn = createCallbackFcn(app, @RNGseedEditFieldValueChanged, true);
            app.RNGseedEditField.Tooltip = {'Seed for noise generation to support repeatability'};
            app.DiagnosticButton = uibutton(app.RightPanel, 'push');
            app.DiagnosticButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticButtonPushed, true);
            app.DiagnosticButton.Text = 'Diagnostic Button';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = {'This area is intentionally left blank for'; 'future development, or individual customizations.'};
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = 'Simulation Summary';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ksim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end